classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        KonvolusiTab              matlab.ui.container.Tab
        ConvolutedImageLabel      matlab.ui.control.Label
        ConvolutedImage           matlab.ui.control.Image
        InputImageButton          matlab.ui.control.Button
        MaskListLabel             matlab.ui.control.Label
        MaskListImage             matlab.ui.control.Image
        ConvolutionButton         matlab.ui.control.Button
        ConvolutionDropDown       matlab.ui.control.DropDown
        ConvolutionDropDownLabel  matlab.ui.control.Label
        ImfilterImage             matlab.ui.control.Image
        ImfilterImageLabel        matlab.ui.control.Label
        InputImage                matlab.ui.control.Image
        InputImageLabel           matlab.ui.control.Label
        SpatialSmoothingTab       matlab.ui.container.Tab
        FrequencySmoothingTab     matlab.ui.container.Tab
        BLPFImageLabel            matlab.ui.control.Label
        GLPFImageLabel            matlab.ui.control.Label
        ILPFImageLabel            matlab.ui.control.Label
        LPFilterButton            matlab.ui.control.Button
        BLPFImage                 matlab.ui.control.Image
        GLPFImage                 matlab.ui.control.Image
        ILPFImage                 matlab.ui.control.Image
        LPInputImageButton        matlab.ui.control.Button
        LPInputImage              matlab.ui.control.Image
        LPInputImageLabel         matlab.ui.control.Label
        HighPassFilterTab         matlab.ui.container.Tab
        BHPFImageLabel            matlab.ui.control.Label
        GHPFImageLabel            matlab.ui.control.Label
        IHPFImageLabel            matlab.ui.control.Label
        HPFilterButton            matlab.ui.control.Button
        BHPFImage                 matlab.ui.control.Image
        GHPFImage                 matlab.ui.control.Image
        IHPFImage                 matlab.ui.control.Image
        HPInputImageButton        matlab.ui.control.Button
        HPInputImage              matlab.ui.control.Image
        HPInputImageLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        allowedFileTypes = "*.bmp;*.tif" % Allowed file types
    end
    
    methods (Access = private)
        function matrix = getSelectedMaskMatrix(app)
            % Define 1st mask
            mask1 = [
                1, 2, 1;
                2, 4, 2;
                1, 2, 1;
            ];
            mask1 = mask1 * (1/16);

            % Define 2nd mask
            mask2 = [
                0, -1, 0;
                -1, 4, -1;
                0, -1, 0;
            ];
            
            % Define 3rd mask
            mask3 = [
                1, 1, 2, 2, 2, 1, 1;
                1, 2, 2, 4, 2, 2, 1;
                2, 2, 4, 8, 4, 2, 2;
                2, 4, 8, 16, 8, 4, 2;
                2, 2, 4, 8, 4, 2, 2;
                1, 2, 2, 4, 2, 2, 1;
                1, 1, 2, 2, 2, 1, 1;
            ];
            mask3 = mask3 * (1/140);

            % Define 4th mask
            mask4 = [
                -1, -1, -1;
                -1, 17, -1;
                -1, -1, -1;
            ];

            % Return based on selected dropdown value
            switch app.ConvolutionDropDown.Value
                case 'Mask 1'
                    matrix = mask1;
                case 'Mask 2'
                    matrix = mask2;
                case 'Mask 3'
                    matrix = mask3;
                case 'Mask 4'
                    matrix = mask4;
            end
        end

        function resultImage = convolute(~, inputImage, kernel)
            inputImage = im2double(inputImage);

            % Get size of input image and kernel
            [imageHeight, imageWidth] = size(inputImage);
            [kernelHeight, kernelWidth] = size(kernel);

            % Initialize resultImage
            resultImage = zeros(imageHeight, imageWidth);

            % Get center of kernel
            kernelCenterRow = floor(kernelHeight / 2) + 1;
            kernelCenterCol = floor(kernelWidth / 2) + 1;

            % Perform convolution
            for i = 1:imageHeight
                for j = 1:imageWidth
                    % Initialize sum
                    sum = 0;

                    for k = 1:kernelHeight
                        for l = 1:kernelWidth
                            % Check if within boundaries of image
                            row = i + k - kernelCenterRow;
                            col = j + l - kernelCenterCol;

                            if row >= 1 && row <= imageHeight && col >= 1 && col <= imageWidth
                                sum = sum + inputImage(row, col) * kernel(k, l);
                            end

                            % If outside of bounds, sum is not increased,
                            % this follows zero padding principle.
                        end
                    end

                    % Update pixel value
                    resultImage(i, j) = sum;
                end
            end
        end

        function filter = createILPFFilter(~, rows, cols)
            P = rows;
            Q = cols;

            % Initialize variables used to make filter H
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);

            % Create mesh grid
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);

            % Calculate H
            H = double(D <= D0);

            filter = H;
        end

        function filter = createGLPFFilter(~, rows, cols)
            P = rows;
            Q = cols;

            % Initialize variables used to make filter H
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);

            % Create mesh grid
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);

            % Calculate H
            H = exp(-(D.^2)./(2*(D0^2)));

            filter = H;
        end

        function filter = createBLPFFilter(~, rows, cols)
            P = rows;
            Q = cols;

            % Initialize variables used to make filter H
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);

            % Create mesh grid
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2 + V.^2);

            % Calculate H
            n = 1;
            H = 1./(1 + (D./D0).^(2*n));

            filter = H;
        end

        function resultImage = ILPF(app, inputImage)
            % Create an image twice the size, pad with zeros
            [M, N] = size(inputImage);
            P = 2 * M;
            Q = 2 * N;
           
            inputImage = im2double(inputImage);
            resultImage = zeros(P, Q);
            for i = 1:P
                for j = 1:Q
                    if i <= M && j <= N
                        resultImage(i, j) = inputImage(i, j);
                    else
                        resultImage(i, j) = 0;
                    end
                end
            end

            % Transform into frequency space
            fourierImage = fft2(double(resultImage));

            % Create LPF ideal filter
            H = app.createILPFFilter(P, Q);

            % Apply transformation
            fourierImage = fourierImage.*H;

            % Transform back to spatial space
            resultImage = real(ifft2(fourierImage));
            resultImage = resultImage(1:M, 1:N);
        end

        function resultImage = GLPF(app, inputImage)
            % Create an image twice the size, pad with zeros
            [M, N] = size(inputImage);
            P = 2 * M;
            Q = 2 * N;
           
            inputImage = im2double(inputImage);
            resultImage = zeros(P, Q);
            for i = 1:P
                for j = 1:Q
                    if i <= M && j <= N
                        resultImage(i, j) = inputImage(i, j);
                    else
                        resultImage(i, j) = 0;
                    end
                end
            end

            % Transform into frequency space
            fourierImage = fft2(double(resultImage));

            % Create LPF gaussian filter
            H = app.createGLPFFilter(P, Q);

            % Apply transformation
            fourierImage = fourierImage.*H;

            % Transform back to spatial space
            resultImage = real(ifft2(fourierImage));
            resultImage = resultImage(1:M, 1:N);
        end

        function resultImage = BLPF(app, inputImage)
            % Create an image twice the size, pad with zeros
            [M, N] = size(inputImage);
            P = 2 * M;
            Q = 2 * N;
           
            inputImage = im2double(inputImage);
            resultImage = zeros(P, Q);
            for i = 1:P
                for j = 1:Q
                    if i <= M && j <= N
                        resultImage(i, j) = inputImage(i, j);
                    else
                        resultImage(i, j) = 0;
                    end
                end
            end

            % Transform into frequency space
            fourierImage = fft2(double(resultImage));

            % Create LPF butterworth filter
            H = app.createBLPFFilter(P, Q);

            % Apply transformation
            fourierImage = fourierImage.*H;

            % Transform back to spatial space
            resultImage = real(ifft2(fourierImage));
            resultImage = resultImage(1:M, 1:N);
        end

        function resultImage = IHPF(app, inputImage)
            % Create an image twice the size, pad with zeros
            [M, N] = size(inputImage);
            P = 2 * M;
            Q = 2 * N;
           
            inputImage = im2double(inputImage);
            resultImage = zeros(P, Q);
            for i = 1:P
                for j = 1:Q
                    if i <= M && j <= N
                        resultImage(i, j) = inputImage(i, j);
                    else
                        resultImage(i, j) = 0;
                    end
                end
            end

            % Transform into frequency space
            fourierImage = fft2(double(resultImage));

            % Create HPF ideal by substracting LPF by one
            H = 1 - app.createILPFFilter(P, Q);

            % Apply transformation
            fourierImage = fourierImage.*H;

            % Transform back to spatial space
            resultImage = real(ifft2(fourierImage));
            resultImage = resultImage(1:M, 1:N);
        end

        function resultImage = GHPF(app, inputImage)
            % Create an image twice the size, pad with zeros
            [M, N] = size(inputImage);
            P = 2 * M;
            Q = 2 * N;
           
            inputImage = im2double(inputImage);
            resultImage = zeros(P, Q);
            for i = 1:P
                for j = 1:Q
                    if i <= M && j <= N
                        resultImage(i, j) = inputImage(i, j);
                    else
                        resultImage(i, j) = 0;
                    end
                end
            end

            % Transform into frequency space
            fourierImage = fft2(double(resultImage));

            % Create HPF gaussian by substracting LPF by one
            H = 1 - app.createGLPFFilter(P, Q);

            % Apply transformation
            fourierImage = fourierImage.*H;

            % Transform back to spatial space
            resultImage = real(ifft2(fourierImage));
            resultImage = resultImage(1:M, 1:N);
        end

        function resultImage = BHPF(app, inputImage)
            % Create an image twice the size, pad with zeros
            [M, N] = size(inputImage);
            P = 2 * M;
            Q = 2 * N;
           
            inputImage = im2double(inputImage);
            resultImage = zeros(P, Q);
            for i = 1:P
                for j = 1:Q
                    if i <= M && j <= N
                        resultImage(i, j) = inputImage(i, j);
                    else
                        resultImage(i, j) = 0;
                    end
                end
            end

            % Transform into frequency space
            fourierImage = fft2(double(resultImage));

            % Create HPF butterworth by substracting LPF by one
            H = 1 - app.createBLPFFilter(P, Q);

            % Apply transformation
            fourierImage = fourierImage.*H;

            % Transform back to spatial space
            resultImage = real(ifft2(fourierImage));
            resultImage = resultImage(1:M, 1:N);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            % Read input image
            inputImage = app.InputImage.ImageSource;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Get the selected convolution matrix
            matrix = app.getSelectedMaskMatrix();

            % Convolute the image and then display it
            if ndims(inputImage) == 3
                redChan = app.convolute(inputImage(:,:,1), matrix);
                greenChan = app.convolute(inputImage(:,:,2), matrix);
                blueChan = app.convolute(inputImage(:,:,3), matrix);
                app.ConvolutedImage.ImageSource = cat(3, redChan, greenChan, blueChan);

                redChan = imfilter(inputImage(:,:,1), matrix);
                greenChan = imfilter(inputImage(:,:,2), matrix);
                blueChan = imfilter(inputImage(:,:,3), matrix);
                app.ImfilterImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.ConvolutedImage.ImageSource = app.convolute(inputImage, matrix);
                app.ImfilterImage.ImageSource = imfilter(inputImage, matrix);
            end
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.InputImage.ImageSource = image;
        end

        % Button pushed function: HPInputImageButton
        function HPInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.HPInputImage.ImageSource = image;
        end

        % Button pushed function: HPFilterButton
        function HPFilterButtonPushed(app, event)
            % Read input image
            inputImage = app.HPInputImage.ImageSource;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Do IHPF filtering
            if ndims(inputImage) == 3
                redChan = app.IHPF(inputImage(:,:,1));
                greenChan = app.IHPF(inputImage(:,:,2));
                blueChan = app.IHPF(inputImage(:,:,3));
                app.IHPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.IHPFImage.ImageSource = app.IHPF(inputImage);
            end

            % Do GHPF filtering
            if ndims(inputImage) == 3
                redChan = app.GHPF(inputImage(:,:,1));
                greenChan = app.GHPF(inputImage(:,:,2));
                blueChan = app.GHPF(inputImage(:,:,3));
                app.GHPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.GHPFImage.ImageSource = app.GHPF(inputImage);
            end

            % Do BHPF filtering
            if ndims(inputImage) == 3
                redChan = app.BHPF(inputImage(:,:,1));
                greenChan = app.BHPF(inputImage(:,:,2));
                blueChan = app.BHPF(inputImage(:,:,3));
                app.BHPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.BHPFImage.ImageSource = app.BHPF(inputImage);
            end
        end

        % Button pushed function: LPInputImageButton
        function LPInputImageButtonPushed(app, event)
            % Allow the user to select a file
            [fileName, pathName, filterIndex] = uigetfile({app.allowedFileTypes, 'Image Files'}, 'Select an image file');
            
            % Check if the user canceled the file selection
            if filterIndex == 0
                return;
            end
            
            % Construct the full file path
            fullFilePath = fullfile(pathName, fileName);
            
            % Read and display the selected image
            image = imread(fullFilePath);
            app.LPInputImage.ImageSource = image;
        end

        % Button pushed function: LPFilterButton
        function LPFilterButtonPushed(app, event)
            % Read input image
            inputImage = app.LPInputImage.ImageSource;
            
            % Do not convolute image if it's empty
            if isempty(inputImage)
                return;
            end

            % Do ILPF filtering
            if ndims(inputImage) == 3
                redChan = app.ILPF(inputImage(:,:,1));
                greenChan = app.ILPF(inputImage(:,:,2));
                blueChan = app.ILPF(inputImage(:,:,3));
                app.ILPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.ILPFImage.ImageSource = app.IHPF(inputImage);
            end

            % Do GLPF filtering
            if ndims(inputImage) == 3
                redChan = app.GLPF(inputImage(:,:,1));
                greenChan = app.GLPF(inputImage(:,:,2));
                blueChan = app.GLPF(inputImage(:,:,3));
                app.GLPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.GLPFImage.ImageSource = app.GHPF(inputImage);
            end

            % Do BLPF filtering
            if ndims(inputImage) == 3
                redChan = app.BLPF(inputImage(:,:,1));
                greenChan = app.BLPF(inputImage(:,:,2));
                blueChan = app.BLPF(inputImage(:,:,3));
                app.BLPFImage.ImageSource = cat(3, redChan, greenChan, blueChan);
            else
                app.BLPFImage.ImageSource = app.BLPF(inputImage);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.InputImageLabel = uilabel(app.KonvolusiTab);
            app.InputImageLabel.Text = 'Citra masukan';
            app.InputImage = uiimage(app.KonvolusiTab);
            app.ImfilterImageLabel = uilabel(app.KonvolusiTab);
            app.ImfilterImageLabel.Text = 'Citra hasil konvolusi (imfilter)';
            app.ImfilterImage = uiimage(app.KonvolusiTab);
            app.ConvolutionDropDownLabel = uilabel(app.KonvolusiTab);
            app.ConvolutionDropDownLabel.Text = 'Mask untuk konvolusi';
            app.ConvolutionDropDown = uidropdown(app.KonvolusiTab);
            app.ConvolutionDropDown.Items = {'Mask 1', 'Mask 2', 'Mask 3', 'Mask 4'};
            app.ConvolutionDropDown.Value = 'Mask 1';
            app.ConvolutionButton = uibutton(app.KonvolusiTab, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Konvolusi';
            app.MaskListImage = uiimage(app.KonvolusiTab);
            app.MaskListImage.ImageSource = fullfile(pathToMLAPP, 'MaskList.png');
            app.MaskListLabel = uilabel(app.KonvolusiTab);
            app.MaskListLabel.Text = 'Pilihan mask untuk konvolusi';
            app.InputImageButton = uibutton(app.KonvolusiTab, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Pilih citra masukan';
            app.ConvolutedImage = uiimage(app.KonvolusiTab);
            app.ConvolutedImageLabel = uilabel(app.KonvolusiTab);
            app.ConvolutedImageLabel.Text = 'Citra hasil konvolusi (app.convolute)';
            app.SpatialSmoothingTab = uitab(app.TabGroup);
            app.SpatialSmoothingTab.Title = 'Smoothing (spatial)';
            app.FrequencySmoothingTab = uitab(app.TabGroup);
            app.FrequencySmoothingTab.Title = 'Smoothing (frequency)';
            app.LPInputImageLabel = uilabel(app.FrequencySmoothingTab);
            app.LPInputImageLabel.Text = 'Citra masukan';
            app.LPInputImage = uiimage(app.FrequencySmoothingTab);
            app.LPInputImageButton = uibutton(app.FrequencySmoothingTab, 'push');
            app.LPInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @LPInputImageButtonPushed, true);
            app.LPInputImageButton.Text = 'Pilih citra masukan';
            app.ILPFImage = uiimage(app.FrequencySmoothingTab);
            app.GLPFImage = uiimage(app.FrequencySmoothingTab);
            app.BLPFImage = uiimage(app.FrequencySmoothingTab);
            app.LPFilterButton = uibutton(app.FrequencySmoothingTab, 'push');
            app.LPFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LPFilterButtonPushed, true);
            app.LPFilterButton.Text = 'Lakukan penapisan';
            app.ILPFImageLabel = uilabel(app.FrequencySmoothingTab);
            app.ILPFImageLabel.Text = 'Citra ILPF';
            app.GLPFImageLabel = uilabel(app.FrequencySmoothingTab);
            app.GLPFImageLabel.Text = 'Citra GLPF';
            app.BLPFImageLabel = uilabel(app.FrequencySmoothingTab);
            app.BLPFImageLabel.Text = 'Citra BLPF';
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High Pass Filter';
            app.HPInputImageLabel = uilabel(app.HighPassFilterTab);
            app.HPInputImageLabel.Text = 'Citra masukan';
            app.HPInputImage = uiimage(app.HighPassFilterTab);
            app.HPInputImageButton = uibutton(app.HighPassFilterTab, 'push');
            app.HPInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @HPInputImageButtonPushed, true);
            app.HPInputImageButton.Text = 'Pilih citra masukan';
            app.IHPFImage = uiimage(app.HighPassFilterTab);
            app.GHPFImage = uiimage(app.HighPassFilterTab);
            app.BHPFImage = uiimage(app.HighPassFilterTab);
            app.HPFilterButton = uibutton(app.HighPassFilterTab, 'push');
            app.HPFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HPFilterButtonPushed, true);
            app.HPFilterButton.Text = 'Lakukan penapisan';
            app.IHPFImageLabel = uilabel(app.HighPassFilterTab);
            app.IHPFImageLabel.Text = 'Citra IHPF';
            app.GHPFImageLabel = uilabel(app.HighPassFilterTab);
            app.GHPFImageLabel.Text = 'Citra GHPF';
            app.BHPFImageLabel = uilabel(app.HighPassFilterTab);
            app.BHPFImageLabel.Text = 'Citra BHPF';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end